classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        StartButton                  matlab.ui.control.Button
        ActualDegreesEditFieldLabel  matlab.ui.control.Label
        ActualDegreesEditField       matlab.ui.control.NumericEditField
        ActualSpeedEditFieldLabel    matlab.ui.control.Label
        ActualSpeedEditField         matlab.ui.control.NumericEditField
        ResetButton                  matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        car % car in gazebo
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            connectionToGazebo();
            app.car = connectToCar();
            velocity = -10;
            app.car = setSteeringAngle(app.car,0); 
            app.car = setVelocity(app.car,velocity);
            figure
            hold on
%             scan = app.car.getScan()
%                 line = plot(scan)
            while(1)
                scan = app.car.getScan();
                line = plot(scan);
                app.ActualDegreesEditField.Value = getSteeringAngle(app.car);
                pause(0.5);
                delete(line);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            resetCar(app.car);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ActualDegreesEditFieldLabel = uilabel(app.LeftPanel);
            app.ActualDegreesEditFieldLabel.Text = 'ActualDegrees';
            app.ActualDegreesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ActualSpeedEditFieldLabel = uilabel(app.LeftPanel);
            app.ActualSpeedEditFieldLabel.Text = 'ActualSpeed';
            app.ActualSpeedEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Rewards')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YTick = [0 0.5 1];
            app.UIAxes.YTickLabel = {'0'; '0.5'; '1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end